classdef FrangiGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainGrid                   matlab.ui.container.GridLayout
        SettingsGrid               matlab.ui.container.GridLayout
        GridLayout59               matlab.ui.container.GridLayout
        AutoUpdateCheckBox         matlab.ui.control.CheckBox
        ColormapDropDownLabel      matlab.ui.control.Label
        ColormapDropDown           matlab.ui.control.DropDown
        VesselnessFilteringLabel   matlab.ui.control.Label
        FrangiFilterSettingsPanel  matlab.ui.container.Panel
        ScalesGrid                 matlab.ui.container.GridLayout
        GridLayout51               matlab.ui.container.GridLayout
        StartEditField             matlab.ui.control.NumericEditField
        StopEditField              matlab.ui.control.NumericEditField
        ArrowLabel                 matlab.ui.control.Label
        nScalesEditFieldLabel      matlab.ui.control.Label
        nScalesEditField           matlab.ui.control.NumericEditField
        RangeLabel                 matlab.ui.control.Label
        GridLayout52               matlab.ui.container.GridLayout
        ScalesDropDownLabel        matlab.ui.control.Label
        ScalesDropDown             matlab.ui.control.DropDown
        UnitsDropDownLabel         matlab.ui.control.Label
        UnitsDropDown              matlab.ui.control.DropDown
        GridLayout53               matlab.ui.container.GridLayout
        ManualLabel                matlab.ui.control.Label
        ScalesTextField            matlab.ui.control.EditField
        GridLayout54               matlab.ui.container.GridLayout
        SensitivityEditFieldLabel  matlab.ui.control.Label
        SensitivityEditField       matlab.ui.control.NumericEditField
        InvertedCheckBox           matlab.ui.control.CheckBox
        ApplyFrangiFilterButton    matlab.ui.control.Button
        GridLayout60               matlab.ui.container.GridLayout
        CLAHEScalesCheckBox        matlab.ui.control.CheckBox
        ContrastScalesCheckBox     matlab.ui.control.CheckBox
        AdjustIndScalesLabel       matlab.ui.control.Label
        GridLayout60_2             matlab.ui.container.GridLayout
        CLAHEFiltCheckBox          matlab.ui.control.CheckBox
        ContrastFiltCheckBox       matlab.ui.control.CheckBox
        AdjustFrangiFilteredLabel  matlab.ui.control.Label
        FrangiFusingSettingsPanel  matlab.ui.container.Panel
        GridLayout55               matlab.ui.container.GridLayout
        GridLayout56               matlab.ui.container.GridLayout
        FusingTechDropDownLabel    matlab.ui.control.Label
        FusingTechDropDown         matlab.ui.control.DropDown
        FuseFrangiInputButton      matlab.ui.control.Button
        GridLayout57               matlab.ui.container.GridLayout
        LinearCombLabel            matlab.ui.control.Label
        GridLayout58_4             matlab.ui.container.GridLayout
        LinCombDropDown            matlab.ui.control.DropDown
        RawEditFieldLabel          matlab.ui.control.Label
        RawEditField               matlab.ui.control.NumericEditField
        FrangiEditFieldLabel       matlab.ui.control.Label
        FrangiEditField            matlab.ui.control.NumericEditField
        GridLayout57_2             matlab.ui.container.GridLayout
        NLCombLabel                matlab.ui.control.Label
        GridLayout58_3             matlab.ui.container.GridLayout
        cutoffEditFieldLabel       matlab.ui.control.Label
        cutoffEditField            matlab.ui.control.NumericEditField
        spreadEditFieldLabel       matlab.ui.control.Label
        spreadEditField            matlab.ui.control.NumericEditField
        GridLayout57_3             matlab.ui.container.GridLayout
        ImageGuidedLabel           matlab.ui.control.Label
        GridLayout58_2             matlab.ui.container.GridLayout
        nbhEditFieldLabel          matlab.ui.control.Label
        nbhEditField               matlab.ui.control.NumericEditField
        smoothEditFieldLabel       matlab.ui.control.Label
        smoothEditField            matlab.ui.control.NumericEditField
        GridLayout57_4             matlab.ui.container.GridLayout
        BinarizeFrangiLabel        matlab.ui.control.Label
        GridLayout58               matlab.ui.container.GridLayout
        ThresholdEditFieldLabel    matlab.ui.control.Label
        ThresholdEditField         matlab.ui.control.NumericEditField
        SmoothEditFieldLabel       matlab.ui.control.Label
        SmoothEditField            matlab.ui.control.NumericEditField
        GridLayout61               matlab.ui.container.GridLayout
        PostCLAHECheckBox          matlab.ui.control.CheckBox
        ClipLimitSliderLabel       matlab.ui.control.Label
        PostClaheClipLim           matlab.ui.control.Slider
        GridLayout61_2             matlab.ui.container.GridLayout
        PostContrastCheckBox       matlab.ui.control.CheckBox
        GammaLabel                 matlab.ui.control.Label
        ContrastGamma              matlab.ui.control.Slider
        SelectFrangiScalesPanel    matlab.ui.container.Panel
        GridLayout46               matlab.ui.container.GridLayout
        ScaleTable                 matlab.ui.control.Table
    end

    
    properties (Access = public)
        FF = Frangi_Filter().empty;
    end
    
    methods (Access = private)
        
        function cMap = get_colormap(app) %#ok<STOUT>
            cMapString = app.ColormapDropDown.Value;
            eval(['cMap = ' cMapString '(256);']);
        end
    end
    
    methods (Access = public)
        
        function Sync_Class_Settings(app)
            app.StartEditField.Value = app.FF.startScale;
            app.StopEditField.Value = app.FF.stopScale;
            app.nScalesEditField.Value = app.FF.nScales;
            app.InvertedCheckBox.Value = app.FF.invert;
            app.SensitivityEditField.Value = app.FF.sensitivity;
            
            % TODO combo settings also have to be synced...
        end
        
        function Close_Request(app)
            app.UIFigure.Visible = 'off';
            delete(app.FF.FigHandles.MainFig);
            app.FF.FigHandles= [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Open_App(app, FrangiFilt)
            movegui(app.UIFigure,'northwest');
            app.FF = FrangiFilt;
            app.FF.GUI = app; % transfer handle to app
            app.FF.Setup_Frangi_Figure();
            
            app.Sync_Class_Settings();
            app.FF.Update_Frangi_Scales();
            
            % setup axis
            app.FF.Plot_Frangi(); % plot whatever data we have right now...
            
            % enable buttons if we have data
            app.ApplyFrangiFilterButton.Enable = ~isempty(app.FF.raw);
            app.FuseFrangiInputButton.Enable = ~isempty(app.FF.raw);
        end

        % Button pushed function: ApplyFrangiFilterButton
        function ApplyFrangiFilterButtonPushed(app, event)
            app.FF.Apply_Frangi();
        end

        % Value changed function: ScalesTextField, StartEditField, 
        % StopEditField, nScalesEditField
        function UpdateScaleTable(app, event)
            app.FF.Update_Frangi_Scales();
        end

        % Callback function: ScaleTable, ScaleTable
        function FrangiTableSelected(app, event)
            selectedRow = event.Indices(1);
            app.FF.Plot_Frangi(true,selectedRow);
        end

        % Display data changed function: ScaleTable
        function FrangiTableChanged(app, event)
            app.FF.Update_Frangi_Combo();
            app.FF.Plot_Frangi(false);
        end

        % Value changed function: ScalesDropDown
        function ScalesDropDownValueChanged(app, event)
            isRange = strcmp(app.ScalesDropDown.Value,'range');
            app.StartEditField.Enable = isRange;
            app.StopEditField.Enable = isRange;
            app.nScalesEditField.Enable = isRange;
            app.RangeLabel.Enable = isRange;
            app.ArrowLabel.Enable = isRange;
            app.ScalesTextField.Enable = ~isRange;
            app.FF.Update_Frangi_Scales();
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            isPhysUnits = strcmp(app.UnitsDropDown.Value,'physical');
            app.StartEditField.RoundFractionalValues = ~isPhysUnits;
            app.StopEditField.RoundFractionalValues = ~isPhysUnits;
            if isPhysUnits
                app.StartEditField.ValueDisplayFormat = '%4.1f';
                app.StopEditField.ValueDisplayFormat = '%4.1f';
            else
                app.StartEditField.ValueDisplayFormat = '%i';
                app.StopEditField.ValueDisplayFormat = '%i';
            end
            app.FF.Update_Frangi_Scales();
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            % update colormaps
            colormap(app.FF.FigHandles.InPlot, app.ColormapDropDown.Value);
            colormap(app.FF.FigHandles.FrangiPlot, app.ColormapDropDown.Value);
            colormap(app.FF.FigHandles.CombiPlot, app.ColormapDropDown.Value);
            colormap(app.FF.FigHandles.ScalePlot, app.ColormapDropDown.Value);
            drawnow();
        end

        % Close request function: UIFigure
        function CloseFigure(app, event)
            app.Close_Request();
        end

        % Callback function
        function AdjScalesCheckBoxValueChanged(app, event)
            app.CLAHEScalesCheckBox.Enable = app.AdjScalesCheckBox.Value;
            app.ContrastScalesCheckBox.Enable = app.AdjScalesCheckBox.Value;
        end

        % Callback function
        function AdjFilteredCheckBoxValueChanged(app, event)
            app.CLAHEFiltCheckBox.Enable = app.AdjFilteredCheckBox.Value;
            app.ContrastFiltCheckBox.Enable = app.AdjFilteredCheckBox.Value;
        end

        % Value changed function: FusingTechDropDown
        function FusingTechDropDownValueChanged(app, event)
            app.LinCombDropDown.Enable = false;
            app.RawEditField.Enable = false;
            app.FrangiEditField.Enable = false;
            
            app.spreadEditField.Enable = false;
            app.cutoffEditField.Enable = false;
            
            app.nbhEditField.Enable = false;
            app.smoothEditField.Enable = false;
            
            app.ThresholdEditField.Enable = false;
            app.SmoothEditField.Enable = false;
            switch app.FusingTechDropDown.Value
                case 'Frangi Only'
                    % nothing to 'reactivate'
                case 'Linear Combination'
                    app.LinCombDropDown.Enable = true;
                    app.RawEditField.Enable = true;
                    app.FrangiEditField.Enable = true;
                case 'Non-Linear Combination'
                    app.spreadEditField.Enable = true;
                    app.cutoffEditField.Enable = true;
                    app.LinCombDropDown.Enable = true;
                    app.RawEditField.Enable = true;
                    app.FrangiEditField.Enable = true;
                case 'Image Guided Filter'
                    app.nbhEditField.Enable = true;
                    app.smoothEditField.Enable = true;
                case 'Binarized Frangi'
                    app.ThresholdEditField.Enable = true;
                    app.SmoothEditField.Enable = true;
            end
            if app.AutoUpdateCheckBox.Value
                app.FF.Update_Frangi_Combo();
            end
        end

        % Button pushed function: FuseFrangiInputButton
        function FuseFrangiInputButtonPushed(app, event)
            app.FF.Update_Frangi_Combo();
        end

        % Value changed function: ContrastGamma, FrangiEditField, 
        % LinCombDropDown, PostCLAHECheckBox, PostClaheClipLim, 
        % PostContrastCheckBox, RawEditField, SmoothEditField, 
        % ThresholdEditField, cutoffEditField, nbhEditField, 
        % smoothEditField, spreadEditField
        function Check_Frangi_Update(app, event)
            app.PostClaheClipLim.Enable = app.PostCLAHECheckBox.Value;
            app.ContrastGamma.Enable = app.PostContrastCheckBox.Value;
            
            if app.AutoUpdateCheckBox.Value
                app.FF.Update_Frangi_Combo();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GUI: Vesselness';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseFigure, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.SettingsGrid = uigridlayout(app.MainGrid);
            app.GridLayout59 = uigridlayout(app.SettingsGrid);
            app.AutoUpdateCheckBox = uicheckbox(app.GridLayout59);
            app.AutoUpdateCheckBox.Text = 'Auto Update?';
            app.AutoUpdateCheckBox.Value = true;
            app.ColormapDropDownLabel = uilabel(app.GridLayout59);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout59);
            app.ColormapDropDown.Items = {'gray', 'hot', 'jet', 'parula'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'gray';
            app.VesselnessFilteringLabel = uilabel(app.SettingsGrid);
            app.VesselnessFilteringLabel.Text = 'Vesselness Filtering';
            app.FrangiFilterSettingsPanel = uipanel(app.SettingsGrid);
            app.FrangiFilterSettingsPanel.Title = 'Frangi Filter Settings';
            app.ScalesGrid = uigridlayout(app.FrangiFilterSettingsPanel);
            app.GridLayout51 = uigridlayout(app.ScalesGrid);
            app.StartEditField = uieditfield(app.GridLayout51, 'numeric');
            app.StartEditField.Limits = [0.01 200];
            app.StartEditField.ValueDisplayFormat = '%4.1f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.StartEditField.Value = 3;
            app.StopEditField = uieditfield(app.GridLayout51, 'numeric');
            app.StopEditField.Limits = [1 500];
            app.StopEditField.RoundFractionalValues = 'on';
            app.StopEditField.ValueDisplayFormat = '%4.1f';
            app.StopEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.StopEditField.Value = 50;
            app.ArrowLabel = uilabel(app.GridLayout51);
            app.ArrowLabel.Text = '<>';
            app.nScalesEditFieldLabel = uilabel(app.GridLayout51);
            app.nScalesEditFieldLabel.Text = 'nScales';
            app.nScalesEditField = uieditfield(app.GridLayout51, 'numeric');
            app.nScalesEditField.Limits = [1 100];
            app.nScalesEditField.RoundFractionalValues = 'on';
            app.nScalesEditField.ValueDisplayFormat = '%i';
            app.nScalesEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.nScalesEditField.Value = 6;
            app.RangeLabel = uilabel(app.GridLayout51);
            app.RangeLabel.Text = 'Range:';
            app.GridLayout52 = uigridlayout(app.ScalesGrid);
            app.ScalesDropDownLabel = uilabel(app.GridLayout52);
            app.ScalesDropDownLabel.Text = 'Scales';
            app.ScalesDropDown = uidropdown(app.GridLayout52);
            app.ScalesDropDown.Items = {'range', 'manual'};
            app.ScalesDropDown.ValueChangedFcn = createCallbackFcn(app, @ScalesDropDownValueChanged, true);
            app.ScalesDropDown.Value = 'range';
            app.UnitsDropDownLabel = uilabel(app.GridLayout52);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.GridLayout52);
            app.UnitsDropDown.Items = {'physical', 'pixel'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'pixel';
            app.GridLayout53 = uigridlayout(app.ScalesGrid);
            app.ManualLabel = uilabel(app.GridLayout53);
            app.ManualLabel.Enable = 'off';
            app.ManualLabel.Text = 'Manual:';
            app.ScalesTextField = uieditfield(app.GridLayout53, 'text');
            app.ScalesTextField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.ScalesTextField.Enable = 'off';
            app.ScalesTextField.Value = '1 5 50 500';
            app.GridLayout54 = uigridlayout(app.ScalesGrid);
            app.SensitivityEditFieldLabel = uilabel(app.GridLayout54);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.GridLayout54, 'numeric');
            app.SensitivityEditField.Limits = [0.001 100];
            app.SensitivityEditField.ValueDisplayFormat = '%4.2f';
            app.SensitivityEditField.Value = 0.05;
            app.InvertedCheckBox = uicheckbox(app.GridLayout54);
            app.InvertedCheckBox.Text = 'Inverted?';
            app.ApplyFrangiFilterButton = uibutton(app.ScalesGrid, 'push');
            app.ApplyFrangiFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFrangiFilterButtonPushed, true);
            app.ApplyFrangiFilterButton.Enable = 'off';
            app.ApplyFrangiFilterButton.Text = 'Apply Frangi Filter';
            app.GridLayout60 = uigridlayout(app.ScalesGrid);
            app.CLAHEScalesCheckBox = uicheckbox(app.GridLayout60);
            app.CLAHEScalesCheckBox.Text = 'CLAHE';
            app.ContrastScalesCheckBox = uicheckbox(app.GridLayout60);
            app.ContrastScalesCheckBox.Text = 'Contrast';
            app.AdjustIndScalesLabel = uilabel(app.GridLayout60);
            app.AdjustIndScalesLabel.Text = 'Adjust Ind. Scales';
            app.GridLayout60_2 = uigridlayout(app.ScalesGrid);
            app.CLAHEFiltCheckBox = uicheckbox(app.GridLayout60_2);
            app.CLAHEFiltCheckBox.Text = 'CLAHE';
            app.ContrastFiltCheckBox = uicheckbox(app.GridLayout60_2);
            app.ContrastFiltCheckBox.Text = 'Contrast';
            app.AdjustFrangiFilteredLabel = uilabel(app.GridLayout60_2);
            app.AdjustFrangiFilteredLabel.Text = 'Adjust Frangi Filtered';
            app.FrangiFusingSettingsPanel = uipanel(app.SettingsGrid);
            app.FrangiFusingSettingsPanel.Title = 'Frangi Fusing Settings';
            app.GridLayout55 = uigridlayout(app.FrangiFusingSettingsPanel);
            app.GridLayout56 = uigridlayout(app.GridLayout55);
            app.FusingTechDropDownLabel = uilabel(app.GridLayout56);
            app.FusingTechDropDownLabel.Text = 'Fusing Tech';
            app.FusingTechDropDown = uidropdown(app.GridLayout56);
            app.FusingTechDropDown.Items = {'Frangi Only', 'Linear Combination', 'Non-Linear Combination', 'Image Guided Filter', 'Binarized Frangi'};
            app.FusingTechDropDown.ValueChangedFcn = createCallbackFcn(app, @FusingTechDropDownValueChanged, true);
            app.FusingTechDropDown.Value = 'Linear Combination';
            app.FuseFrangiInputButton = uibutton(app.GridLayout55, 'push');
            app.FuseFrangiInputButton.ButtonPushedFcn = createCallbackFcn(app, @FuseFrangiInputButtonPushed, true);
            app.FuseFrangiInputButton.Enable = 'off';
            app.FuseFrangiInputButton.Text = 'Fuse Frangi & Input';
            app.GridLayout57 = uigridlayout(app.GridLayout55);
            app.LinearCombLabel = uilabel(app.GridLayout57);
            app.LinearCombLabel.Text = {'Linear'; 'Comb.'; ''};
            app.GridLayout58_4 = uigridlayout(app.GridLayout57);
            app.LinCombDropDown = uidropdown(app.GridLayout58_4);
            app.LinCombDropDown.Items = {'sum', 'prod'};
            app.LinCombDropDown.Editable = 'on';
            app.LinCombDropDown.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.LinCombDropDown.Value = 'prod';
            app.RawEditFieldLabel = uilabel(app.GridLayout58_4);
            app.RawEditFieldLabel.Text = 'Raw';
            app.RawEditField = uieditfield(app.GridLayout58_4, 'numeric');
            app.RawEditField.ValueDisplayFormat = '%2.1f';
            app.RawEditField.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.RawEditField.Value = 1;
            app.FrangiEditFieldLabel = uilabel(app.GridLayout58_4);
            app.FrangiEditFieldLabel.Text = 'Frangi';
            app.FrangiEditField = uieditfield(app.GridLayout58_4, 'numeric');
            app.FrangiEditField.ValueDisplayFormat = '%2.1f';
            app.FrangiEditField.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.FrangiEditField.Value = 1;
            app.GridLayout57_2 = uigridlayout(app.GridLayout55);
            app.NLCombLabel = uilabel(app.GridLayout57_2);
            app.NLCombLabel.Text = {'NL'; 'Comb.'; ''};
            app.GridLayout58_3 = uigridlayout(app.GridLayout57_2);
            app.cutoffEditFieldLabel = uilabel(app.GridLayout58_3);
            app.cutoffEditFieldLabel.Enable = 'off';
            app.cutoffEditFieldLabel.Text = {'cut'; 'off'};
            app.cutoffEditField = uieditfield(app.GridLayout58_3, 'numeric');
            app.cutoffEditField.Limits = [0 1];
            app.cutoffEditField.ValueDisplayFormat = '%2.2f';
            app.cutoffEditField.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.cutoffEditField.Enable = 'off';
            app.cutoffEditField.Value = 0.5;
            app.spreadEditFieldLabel = uilabel(app.GridLayout58_3);
            app.spreadEditFieldLabel.Enable = 'off';
            app.spreadEditFieldLabel.Text = 'spread';
            app.spreadEditField = uieditfield(app.GridLayout58_3, 'numeric');
            app.spreadEditField.Limits = [0.01 Inf];
            app.spreadEditField.ValueDisplayFormat = '%2.1f';
            app.spreadEditField.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.spreadEditField.Enable = 'off';
            app.spreadEditField.Value = 10;
            app.GridLayout57_3 = uigridlayout(app.GridLayout55);
            app.ImageGuidedLabel = uilabel(app.GridLayout57_3);
            app.ImageGuidedLabel.Text = {'Image'; 'Guided'};
            app.GridLayout58_2 = uigridlayout(app.GridLayout57_3);
            app.nbhEditFieldLabel = uilabel(app.GridLayout58_2);
            app.nbhEditFieldLabel.Enable = 'off';
            app.nbhEditFieldLabel.Text = 'nbh';
            app.nbhEditField = uieditfield(app.GridLayout58_2, 'numeric');
            app.nbhEditField.Limits = [1 100];
            app.nbhEditField.RoundFractionalValues = 'on';
            app.nbhEditField.ValueDisplayFormat = '%i';
            app.nbhEditField.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.nbhEditField.Enable = 'off';
            app.nbhEditField.Tooltip = {'Size of the rectangular neighborhood around each pixel used in guided filtering'};
            app.nbhEditField.Value = 3;
            app.smoothEditFieldLabel = uilabel(app.GridLayout58_2);
            app.smoothEditFieldLabel.Enable = 'off';
            app.smoothEditFieldLabel.Text = 'smooth';
            app.smoothEditField = uieditfield(app.GridLayout58_2, 'numeric');
            app.smoothEditField.Limits = [0 0.5];
            app.smoothEditField.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.smoothEditField.Enable = 'off';
            app.smoothEditField.Tooltip = {' Amount of smoothing'};
            app.smoothEditField.Value = 0.01;
            app.GridLayout57_4 = uigridlayout(app.GridLayout55);
            app.BinarizeFrangiLabel = uilabel(app.GridLayout57_4);
            app.BinarizeFrangiLabel.Text = {'Binarize'; 'Frangi'};
            app.GridLayout58 = uigridlayout(app.GridLayout57_4);
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout58);
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.GridLayout58, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.ThresholdEditField.Enable = 'off';
            app.SmoothEditFieldLabel = uilabel(app.GridLayout58);
            app.SmoothEditFieldLabel.Enable = 'off';
            app.SmoothEditFieldLabel.Text = 'Smooth';
            app.SmoothEditField = uieditfield(app.GridLayout58, 'numeric');
            app.SmoothEditField.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.SmoothEditField.Enable = 'off';
            app.GridLayout61 = uigridlayout(app.GridLayout55);
            app.PostCLAHECheckBox = uicheckbox(app.GridLayout61);
            app.PostCLAHECheckBox.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.PostCLAHECheckBox.Text = 'Post CLAHE';
            app.PostCLAHECheckBox.Value = true;
            app.ClipLimitSliderLabel = uilabel(app.GridLayout61);
            app.ClipLimitSliderLabel.Text = 'Clip Limit';
            app.PostClaheClipLim = uislider(app.GridLayout61);
            app.PostClaheClipLim.Limits = [0.0001 0.1];
            app.PostClaheClipLim.MajorTicks = [];
            app.PostClaheClipLim.MajorTickLabels = {};
            app.PostClaheClipLim.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.PostClaheClipLim.MinorTicks = [];
            app.PostClaheClipLim.Value = 0.02;
            app.GridLayout61_2 = uigridlayout(app.GridLayout55);
            app.PostContrastCheckBox = uicheckbox(app.GridLayout61_2);
            app.PostContrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.PostContrastCheckBox.Text = 'Post Contrast';
            app.PostContrastCheckBox.Value = true;
            app.GammaLabel = uilabel(app.GridLayout61_2);
            app.GammaLabel.Text = 'Gamma';
            app.ContrastGamma = uislider(app.GridLayout61_2);
            app.ContrastGamma.Limits = [0.01 3];
            app.ContrastGamma.MajorTicks = [];
            app.ContrastGamma.MajorTickLabels = {};
            app.ContrastGamma.ValueChangedFcn = createCallbackFcn(app, @Check_Frangi_Update, true);
            app.ContrastGamma.MinorTicks = [];
            app.ContrastGamma.Value = 1.1;
            app.SelectFrangiScalesPanel = uipanel(app.SettingsGrid);
            app.SelectFrangiScalesPanel.Title = 'Select Frangi Scales';
            app.GridLayout46 = uigridlayout(app.SelectFrangiScalesPanel);
            app.ScaleTable = uitable(app.GridLayout46);
            app.ScaleTable.ColumnName = {'scale'; 'selected'};
            app.ScaleTable.RowName = {''};
            app.ScaleTable.ColumnEditable = [true true];
            app.ScaleTable.CellEditCallback = createCallbackFcn(app, @FrangiTableSelected, true);
            app.ScaleTable.CellSelectionCallback = createCallbackFcn(app, @FrangiTableSelected, true);
            app.ScaleTable.DisplayDataChangedFcn = createCallbackFcn(app, @FrangiTableChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrangiGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Open_App(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end